classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        sinn2pi4cosn2pi8Label         matlab.ui.control.Label
        expn112sin3pin1Label          matlab.ui.control.Label
        cos3pi7nLabel                 matlab.ui.control.Label
        TransformacionYSlider_3       matlab.ui.control.Slider
        TransformacionYSlider_3Label  matlab.ui.control.Label
        TransformacionXSlider_3       matlab.ui.control.Slider
        TransformacionXSlider_3Label  matlab.ui.control.Label
        TransformacionYSlider_2       matlab.ui.control.Slider
        TransformacionYSlider_2Label  matlab.ui.control.Label
        TransformacionXSlider_2       matlab.ui.control.Slider
        TransformacionXSlider_2Label  matlab.ui.control.Label
        TransformacionYSlider         matlab.ui.control.Slider
        TransformacionYSliderLabel    matlab.ui.control.Label
        TransformacionXSlider         matlab.ui.control.Slider
        TransformacionXSliderLabel    matlab.ui.control.Label
        sinn2pi4cosn2pi8Button        matlab.ui.control.Button
        expn112sin3pin1Button         matlab.ui.control.Button
        cos3pi7nButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function ButtonPushed(app, event)
            
        end

        % Callback function: not associated with a component
        function GraficarButtonPushed(app, event)
            cal = app.fnEditField.Value;
           

            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;
            ymin = app.yminEditField.Value;
            ymax = app.ymaxEditField.Value;
            n = xmin:xmax;
            stem(app.UIAxes,n,cal,'LineWidth',3);
            
        end

        % Button pushed function: cos3pi7nButton
        function cos3pi7nButtonPushed2(app, event)
            n=-5:5;
            f= cos(3*pi/7*n);
            stem(app.UIAxes,n,f)
        end

        % Button pushed function: expn112sin3pin1Button
        function expn112sin3pin1ButtonPushed(app, event)
            n1=-5:5;
            f1=exp(-n1/12).*sin(3*pi*n1);
            stem(app.UIAxes,n1,f1)
        end

        % Button pushed function: sinn2pi4cosn2pi8Button
        function sinn2pi4cosn2pi8ButtonPushed(app, event)
            n2=-5:5;
            f2=sin(n2*pi/4).*cos(n2*pi/8);
            stem(app.UIAxes,n2,f2);
        end

        % Value changed function: TransformacionXSlider
        function TransformacionXSliderValueChanged(app, event)
            value = app.TransformacionXSlider.Value;
            n=-5:5;
            f= cos((3*pi/7*n).*value);
            stem(app.UIAxes,n,f)
            
        end

        % Value changed function: TransformacionYSlider
        function TransformacionYSliderValueChanged(app, event)
            value = app.TransformacionYSlider.Value;
             n=-5:5;
            f= cos(3*pi/7*n).*value;
            stem(app.UIAxes,n,f)
            app.UIAxes.YLim = [-100 100];
        end

        % Value changed function: TransformacionXSlider_2
        function TransformacionXSlider_2ValueChanged(app, event)
            value = app.TransformacionXSlider_2.Value;
            n1=-5:5;
            f1=exp((-n1/12).*value).*sin((3*pi*n1).*value);
            stem(app.UIAxes,n1,f1)
            
        end

        % Value changed function: TransformacionYSlider_2
        function TransformacionYSlider_2ValueChanged(app, event)
            value = app.TransformacionYSlider_2.Value;
            n1=-5:5;
            f1=exp(-n1/12).*sin(3*pi*n1).*value;
            stem(app.UIAxes,n1,f1)
            app.UIAxes.YLim = [-100 100];
        end

        % Value changed function: TransformacionXSlider_3
        function TransformacionXSlider_3ValueChanged(app, event)
            value = app.TransformacionXSlider_3.Value;
            n2=-5:5;
            f2=sin(n2*pi/4.*value).*cos(n2*pi/8.*value);
            stem(app.UIAxes,n2,f2);
            
        end

        % Value changed function: TransformacionYSlider_3
        function TransformacionYSlider_3ValueChanged(app, event)
            value = app.TransformacionYSlider_3.Value;
            n2=-5:5;
            f2=sin(n2*pi/4).*cos(n2*pi/8).*value;
            stem(app.UIAxes,n2,f2);
            app.UIAxes.YLim = [-100 100];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'f[n]')
            zlabel(app.UIAxes, 'Z')
            app.cos3pi7nButton = uibutton(app.UIFigure, 'push');
            app.cos3pi7nButton.ButtonPushedFcn = createCallbackFcn(app, @cos3pi7nButtonPushed2, true);
            app.cos3pi7nButton.Text = 'cos(3*pi/7*n)';
            app.expn112sin3pin1Button = uibutton(app.UIFigure, 'push');
            app.expn112sin3pin1Button.ButtonPushedFcn = createCallbackFcn(app, @expn112sin3pin1ButtonPushed, true);
            app.expn112sin3pin1Button.Text = 'exp(-n1/12).*sin(3*pi*n1)';
            app.sinn2pi4cosn2pi8Button = uibutton(app.UIFigure, 'push');
            app.sinn2pi4cosn2pi8Button.ButtonPushedFcn = createCallbackFcn(app, @sinn2pi4cosn2pi8ButtonPushed, true);
            app.sinn2pi4cosn2pi8Button.Text = 'sin(n2*pi/4).*cos(n2*pi/8)';
            app.TransformacionXSliderLabel = uilabel(app.UIFigure);
            app.TransformacionXSliderLabel.Text = 'Transformacion X';
            app.TransformacionXSlider = uislider(app.UIFigure);
            app.TransformacionXSlider.ValueChangedFcn = createCallbackFcn(app, @TransformacionXSliderValueChanged, true);
            app.TransformacionYSliderLabel = uilabel(app.UIFigure);
            app.TransformacionYSliderLabel.Text = 'Transformacion Y';
            app.TransformacionYSlider = uislider(app.UIFigure);
            app.TransformacionYSlider.ValueChangedFcn = createCallbackFcn(app, @TransformacionYSliderValueChanged, true);
            app.TransformacionXSlider_2Label = uilabel(app.UIFigure);
            app.TransformacionXSlider_2Label.Text = 'Transformacion X';
            app.TransformacionXSlider_2 = uislider(app.UIFigure);
            app.TransformacionXSlider_2.ValueChangedFcn = createCallbackFcn(app, @TransformacionXSlider_2ValueChanged, true);
            app.TransformacionYSlider_2Label = uilabel(app.UIFigure);
            app.TransformacionYSlider_2Label.Text = 'Transformacion Y';
            app.TransformacionYSlider_2 = uislider(app.UIFigure);
            app.TransformacionYSlider_2.ValueChangedFcn = createCallbackFcn(app, @TransformacionYSlider_2ValueChanged, true);
            app.TransformacionXSlider_3Label = uilabel(app.UIFigure);
            app.TransformacionXSlider_3Label.Text = 'Transformacion X';
            app.TransformacionXSlider_3 = uislider(app.UIFigure);
            app.TransformacionXSlider_3.ValueChangedFcn = createCallbackFcn(app, @TransformacionXSlider_3ValueChanged, true);
            app.TransformacionYSlider_3Label = uilabel(app.UIFigure);
            app.TransformacionYSlider_3Label.Text = 'Transformacion Y';
            app.TransformacionYSlider_3 = uislider(app.UIFigure);
            app.TransformacionYSlider_3.ValueChangedFcn = createCallbackFcn(app, @TransformacionYSlider_3ValueChanged, true);
            app.cos3pi7nLabel = uilabel(app.UIFigure);
            app.cos3pi7nLabel.Text = 'cos(3*pi/7*n)';
            app.expn112sin3pin1Label = uilabel(app.UIFigure);
            app.expn112sin3pin1Label.Text = 'exp(-n1/12).*sin(3*pi*n1)';
            app.sinn2pi4cosn2pi8Label = uilabel(app.UIFigure);
            app.sinn2pi4cosn2pi8Label.Text = 'sin(n2*pi/4).*cos(n2*pi/8)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end